classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TransformationMatrixSwitch     matlab.ui.control.Switch
        TransformationMatrixSwitchLabel  matlab.ui.control.Label
        TranslationTypeSwitch          matlab.ui.control.Switch
        TranslationTypeSwitchLabel     matlab.ui.control.Label
        PresetsTestingOnlyButtonGroup  matlab.ui.container.ButtonGroup
        Preset3Button                  matlab.ui.control.ToggleButton
        Preset2Button                  matlab.ui.control.ToggleButton
        ResetButton                    matlab.ui.control.ToggleButton
        VisualControlsPanel            matlab.ui.container.Panel
        VectorfromcoilSwitch           matlab.ui.control.Switch
        VectorfromcoilSwitchLabel      matlab.ui.control.Label
        VectortocoilSwitch             matlab.ui.control.Switch
        VectortocoilSwitchLabel        matlab.ui.control.Label
        HeadCompartmentsButtonGroup    matlab.ui.container.ButtonGroup
        EyesButton                     matlab.ui.control.ToggleButton
        VentriclesButton               matlab.ui.control.ToggleButton
        WMButton                       matlab.ui.control.ToggleButton
        GMButton                       matlab.ui.control.ToggleButton
        CSFButton                      matlab.ui.control.ToggleButton
        BoneButton                     matlab.ui.control.ToggleButton
        SkinButton_2                   matlab.ui.control.ToggleButton
        ContributorsAltonMilesGrahamOrnsteinKseniiaRomanovaPanel  matlab.ui.container.Panel
        Versionv012alphaLabel          matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        AxisLengthSlider               matlab.ui.control.Slider
        AxisLengthSliderLabel          matlab.ui.control.Label
        SliderControlsPanel            matlab.ui.container.Panel
        ChosenPointcmLabel             matlab.ui.control.Label
        PointZValEditField             matlab.ui.control.NumericEditField
        PointYValEditField             matlab.ui.control.NumericEditField
        PointXValEditField             matlab.ui.control.NumericEditField
        UpdateDisplayButton            matlab.ui.control.Button
        RadiusmmSlider                 matlab.ui.control.Slider
        RadiusmmSliderLabel            matlab.ui.control.Label
        UpdatePointButton              matlab.ui.control.Button
        ResetTranslationButton         matlab.ui.control.Button
        ResetRotationButton            matlab.ui.control.Button
        CoilAxisRotationSlider         matlab.ui.control.Slider
        CoilAxisRotationSliderLabel    matlab.ui.control.Label
        YAxisRotationSlider            matlab.ui.control.Slider
        YAxisRotationSliderLabel       matlab.ui.control.Label
        XAxisRotationSlider            matlab.ui.control.Slider
        XAxisRotationSliderLabel       matlab.ui.control.Label
        ZTranslationSlider             matlab.ui.control.Slider
        ZTranslationSliderLabel        matlab.ui.control.Label
        YTranslationSlider             matlab.ui.control.Slider
        YTranslationSliderLabel        matlab.ui.control.Label
        XTranslationSlider             matlab.ui.control.Slider
        XTranslationSliderLabel        matlab.ui.control.Label
        TransformationMatrixPanel      matlab.ui.container.Panel
        MatrixField44                  matlab.ui.control.NumericEditField
        MatrixField33                  matlab.ui.control.NumericEditField
        MatrixField34                  matlab.ui.control.NumericEditField
        MatrixField24                  matlab.ui.control.NumericEditField
        MatrixField43                  matlab.ui.control.NumericEditField
        MatrixField23                  matlab.ui.control.NumericEditField
        MatrixField14                  matlab.ui.control.NumericEditField
        MatrixField12                  matlab.ui.control.NumericEditField
        MatrixField22                  matlab.ui.control.NumericEditField
        MatrixField32                  matlab.ui.control.NumericEditField
        MatrixField13                  matlab.ui.control.NumericEditField
        MatrixField11                  matlab.ui.control.NumericEditField
        MatrixField42                  matlab.ui.control.NumericEditField
        MatrixField41                  matlab.ui.control.NumericEditField
        MatrixField31                  matlab.ui.control.NumericEditField
        MatrixField21                  matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        shiftX % The shift in position for the X-Axis (Coil)
        shiftY % The shift in position for the Y-Axis (Coil)
        shiftZ % The shift in position for the Z-Axis (Coil)
        P % The points of the coil
        skinPoint % The chosen point on the skin
        directionNVector % Normal direction vector for coil placement
    end
    
    properties (Access = private)
        dcm_obj % Handle for the UIFigure
        magn % Magnitude of vector
    end
    
    methods (Access = public)
        
        function [] = updateImage(app)
           
            updateimage(app); % calls function in AppFunctions/updateimage.m

        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            addpathes();

            load('CombinedMesh.mat', "P")
            app.P = P;

            %   Scale Matrix (m to cm)
            scaleMatrix = [100 0 0;
                0 100 0;
                0 0 100];

            %   Scales the brain's points to cm
            for j = 1:size(app.P, 1)
                scaledPoint = scaleMatrix * app.P(j,:)';
                app.P(j,:) = scaledPoint';
            end

            %sets a few properties of the UIAxes
            hold(app.UIAxes, "on");
            grid(app.UIAxes,'on');
            app.UIAxes.XLim = [-20, 20];
            app.UIAxes.YLim = [-20, 20];
            app.UIAxes.ZLim = [-20, 20];


            %   Labels the units
            xlabel(app.UIAxes, 'x, cm'); 
            ylabel(app.UIAxes, 'y, cm'); 
            zlabel(app.UIAxes, 'z, cm');  

            %   Sets the update function for clicking points on the UIAxes
            datacursormode(app.UIFigure, 'on');
            app.dcm_obj = datacursormode(app.UIFigure);
            set(app.dcm_obj,'UpdateFcn',@pointClickUpdateFunction);


            %   displays the initial image:
            updateImage(app);

        end

        % Value changed function: MatrixField11, MatrixField12, 
        % ...and 14 other components
        function MatrixFieldValueChanged(app, event)

            %   Update the image
            updateImage(app);
            
        end

        % Value changed function: XTranslationSlider
        function XTranslationSliderValueChanged(app, event)
            %   Adds the new x-translation to the already existing
            %   x-translation
            value = app.XTranslationSlider.Value;
            app.MatrixField14.Value = app.MatrixField14.Value + value;

            %   Reset the translation slider to be centered at 0
            app.XTranslationSlider.Value = 0;

            %   updates the image
            updateImage(app);
            
        end

        % Value changed function: YTranslationSlider
        function YTranslationSliderValueChanged(app, event)
            value = app.YTranslationSlider.Value;
            app.MatrixField24.Value = app.MatrixField24.Value + value;

            %   Reset the translation slider to be centered at 0
            app.YTranslationSlider.Value = 0;

            %   updates the image
            updateImage(app);
            
        end

        % Value changed function: ZTranslationSlider
        function ZTranslationSliderValueChanged(app, event)
            value = app.ZTranslationSlider.Value;
            app.MatrixField34.Value = app.MatrixField34.Value + value;

            %   Reset the translation slider to be centered at 0
            app.ZTranslationSlider.Value = 0;

            %   updates the image
            updateImage(app);
            
        end

        % Value changed function: XAxisRotationSlider
        function XAxisRotationSliderValueChanged(app, event)
            value = app.XAxisRotationSlider.Value * pi;
            
            %   Reset the rotation slider to be centered at 0
            app.XAxisRotationSlider.Value = 0;

            %   getting the rotational matrix for the specified rotation
            %   around the x-axis
            Rx = [1, 0, 0; 
                0, cos(value), sin(value); 
                0, -sin(value), cos(value)];    %X's rotation matrix

            %   turning the edit fields for the rotation in the matrix into a matrix
            CurrentRot = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];  %current rotation matrix


            %   applying the rotation around the x-axis to the current
            %   rotation matrix
            newRot = Rx*CurrentRot;

            %   Updating all of the fields in the matrix
            app.MatrixField11.Value = newRot(1, 1);
            app.MatrixField12.Value = newRot(1, 2);
            app.MatrixField13.Value = newRot(1, 3);
            app.MatrixField21.Value = newRot(2, 1);
            app.MatrixField22.Value = newRot(2, 2);
            app.MatrixField23.Value = newRot(2, 3);
            app.MatrixField31.Value = newRot(3, 1);
            app.MatrixField32.Value = newRot(3, 2);
            app.MatrixField33.Value = newRot(3, 3);

            %   Update the display
            updateImage(app);
            
        end

        % Value changed function: YAxisRotationSlider
        function YAxisRotationSliderValueChanged(app, event)
            value = app.YAxisRotationSlider.Value * pi;

            %   Reset the rotation slider to be centered at 0
            app.YAxisRotationSlider.Value = 0;

            %   getting the rotational matrix for the specified rotation
            %   around the y-axis
            Ry = [cos(value), 0, -sin(value);
                0, 1, 0;
                sin(value), 0, cos(value)];  %Y's rotation matrix

            %   turning the edit fields for the rotation in the matrix into a matrix
            CurrentRot = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];  %current rotation matrix


            %   applying the rotation around the y-axis to the current
            %   rotation matrix
            newRot = Ry*CurrentRot;

            %   Updating all of the fields in the matrix
            app.MatrixField11.Value = newRot(1, 1);
            app.MatrixField12.Value = newRot(1, 2);
            app.MatrixField13.Value = newRot(1, 3);
            app.MatrixField21.Value = newRot(2, 1);
            app.MatrixField22.Value = newRot(2, 2);
            app.MatrixField23.Value = newRot(2, 3);
            app.MatrixField31.Value = newRot(3, 1);
            app.MatrixField32.Value = newRot(3, 2);
            app.MatrixField33.Value = newRot(3, 3);

            %   Update the display
            updateImage(app);
            
        end

        % Value changed function: CoilAxisRotationSlider
        function CoilAxisRotationSliderValueChanged(app, event)
            value = app.CoilAxisRotationSlider.Value * pi;

            %   Reset the rotation slider to be centered at 0
            app.CoilAxisRotationSlider.Value = 0;

            %   getting the rotational matrix for the specified rotation
            %   around the z-axis
            Rz = [cos(value), -sin(value), 0;
                sin(value), cos(value), 0;
                0, 0, 1];    %Z's rotation matrix

            %   turning the edit fields for the rotation in the matrix into a matrix
            CurrentRot = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];  %current rotation matrix


            %   applying the rotation around the y-axis to the current
            %   rotation matrix
            newRot = CurrentRot*Rz;

            %   Updating all of the fields in the matrix
            app.MatrixField11.Value = newRot(1, 1);
            app.MatrixField12.Value = newRot(1, 2);
            app.MatrixField13.Value = newRot(1, 3);
            app.MatrixField21.Value = newRot(2, 1);
            app.MatrixField22.Value = newRot(2, 2);
            app.MatrixField23.Value = newRot(2, 3);
            app.MatrixField31.Value = newRot(3, 1);
            app.MatrixField32.Value = newRot(3, 2);
            app.MatrixField33.Value = newRot(3, 3);

            %   Update the display
            updateImage(app);
            
        end

        % Button pushed function: ResetRotationButton
        function ResetRotationButtonPushed(app, event)
            
            %   Restore rotation matrix to identity matrix
            app.MatrixField11.Value = 1;
            app.MatrixField12.Value = 0;
            app.MatrixField13.Value = 0;
            app.MatrixField21.Value = 0;
            app.MatrixField22.Value = 1;
            app.MatrixField23.Value = 0;
            app.MatrixField31.Value = 0;
            app.MatrixField32.Value = 0;
            app.MatrixField33.Value = 1;

            %   Update visuals
            updateImage(app);


        end

        % Value changed function: AxisLengthSlider
        function AxisLengthSliderValueChanged(app, event)
            value = app.AxisLengthSlider.Value;
            
            app.UIAxes.XLim = [-value,value];
            app.UIAxes.YLim = [-value,value];
            app.UIAxes.ZLim = [-value,value];

        end

        % Button pushed function: ResetTranslationButton
        function ResetTranslationButtonPushed(app, event)
            
            %   Restore translation matrix
            app.MatrixField14.Value = 0;
            app.MatrixField24.Value = 0;
            app.MatrixField34.Value = 0;

            %   Update visuals
            updateImage(app);
            
        end

        % Button down function: HeadCompartmentsButtonGroup
        function HeadCompartmentsButtonGroupButtonDown(app, event)
            
        end

        % Selection changed function: HeadCompartmentsButtonGroup
        function HeadCompartmentsButtonGroupSelectionChanged(app, event)
            %selectedButton = app.HeadCompartmentsButtonGroup.SelectedObject;

            %   Update visuals
            updateImage(app);

        end

        % Value changed function: VectorfromcoilSwitch, VectortocoilSwitch
        function VectortocoilSwitchValueChanged(app, event)
            %   Update visuals
            updateImage(app);
        end

        % Selection changed function: PresetsTestingOnlyButtonGroup
        function PresetButton(app, event)
            selectedButton = app.PresetsTestingOnlyButtonGroup.SelectedObject;
            
            % preset 1
            if(selectedButton == app.ResetButton)
                app.MatrixField11.Value = 1;
                app.MatrixField12.Value = 0;
                app.MatrixField13.Value = 0;
                app.MatrixField14.Value = 0;
                app.MatrixField21.Value = 0;
                app.MatrixField22.Value = 1;
                app.MatrixField23.Value = 0;
                app.MatrixField24.Value = 0;
                app.MatrixField31.Value = 0;
                app.MatrixField32.Value = 0;
                app.MatrixField33.Value = 1;
                app.MatrixField34.Value = 0;
            % preset 2
            elseif(selectedButton == app.Preset2Button)
                app.MatrixField11.Value = 0.1238;
                app.MatrixField12.Value = 0.9286;
                app.MatrixField13.Value = -0.35;
                app.MatrixField14.Value = 5;
                app.MatrixField21.Value = 0.9329;
                app.MatrixField22.Value = -0.2291;
                app.MatrixField23.Value = -0.2779;
                app.MatrixField24.Value = 5;
                app.MatrixField31.Value = -0.3382;
                app.MatrixField32.Value = -0.2921;
                app.MatrixField33.Value = -0.8946;
                app.MatrixField34.Value = 15;
            % preset 3
            elseif(selectedButton == app.Preset3Button)
                app.MatrixField11.Value = -0.8545;
                app.MatrixField12.Value = -0.195;
                app.MatrixField13.Value = -0.4814;
                app.MatrixField14.Value = 10;
                app.MatrixField21.Value = -0.4446;
                app.MatrixField22.Value = 0.7538;
                app.MatrixField23.Value = 0.4839;
                app.MatrixField24.Value = -10;
                app.MatrixField31.Value = 0.2685;
                app.MatrixField32.Value = 0.6275;
                app.MatrixField33.Value = -0.7309;
                app.MatrixField34.Value = 15;
            end

            %   Update visuals
            updateImage(app);

        end

        % Value changed function: TranslationTypeSwitch
        function TranslationTypeSwitchValueChanged(app, event)
            value = app.TranslationTypeSwitch.Value;
            switch value
                case 'Manual'

                    %   Enables visibility of the translation controls
                    app.XTranslationSlider.Visible = "on";
                    app.XTranslationSliderLabel.Visible = "on";
                    app.YTranslationSlider.Visible = "on";
                    app.YTranslationSliderLabel.Visible = "on";
                    app.ZTranslationSlider.Visible = "on";
                    app.ZTranslationSliderLabel.Visible = "on";

                    %   Disables visibility of the point controls
                    app.RadiusmmSlider.Visible = "off";
                    app.RadiusmmSliderLabel.Visible = "off";
                    app.UpdatePointButton.Visible = "off";
                    app.UpdateDisplayButton.Visible = "off";
                    app.PointXValEditField.Visible = "off";
                    app.PointYValEditField.Visible = "off";
                    app.PointZValEditField.Visible = "off";
                    app.ChosenPointcmLabel.Visible = "off";

                case 'Automatic'

                    %   Disables visibility of the translation controls
                    app.XTranslationSlider.Visible = "off";
                    app.XTranslationSliderLabel.Visible = "off";
                    app.YTranslationSlider.Visible = "off";
                    app.YTranslationSliderLabel.Visible = "off";
                    app.ZTranslationSlider.Visible = "off";
                    app.ZTranslationSliderLabel.Visible = "off";

                    %   Disables visibility of the point controls
                    app.RadiusmmSlider.Visible = "on";
                    app.RadiusmmSliderLabel.Visible = "on";
                    app.UpdatePointButton.Visible = "on";
                    app.UpdateDisplayButton.Visible = "on";
                    app.PointXValEditField.Visible = "on";
                    app.PointYValEditField.Visible = "on";
                    app.PointZValEditField.Visible = "on";
                    app.ChosenPointcmLabel.Visible = "on";
            end
        end

        % Button pushed function: UpdateDisplayButton
        function UpdateDisplayButtonPushed(app, event)
            
            point = [app.PointXValEditField.Value, app.PointYValEditField.Value, app.PointZValEditField.Value];

            %   Finds nearest point (center of triangle) on Skin
            dt = stlread('120111_skin_headreco.stl');

            Ps = dt.Points;
            %   Ps is by default 10 times larger than the brain so scale it
            %   by 0.1 (dm to cm)
            for i = 1:size(Ps,1)
                Ps(i,:) = Ps(i,:) * (eye(3).*1e-3);
            end

            %   Find closest point on the skin's surface from the
            %   user-selected point


            %   Find range of points on the skin
            load('CombinedMesh.mat', 'Center', 'normals', 'Indicator', 'P', 't');
            

            eligibleCenters = Center(Indicator==1,:);   % Indicator==1 means on the skin
            eligibleNormals = normals(Indicator==1,:);  % Indicator==1 means on the skin
            eligibleT = t(Indicator==1,:);

            Idx1 = knnsearch(eligibleCenters, point);
            nF = eligibleCenters(Idx1,:);

            DT = triangulation(eligibleT,P);
            tNeighbor = neighbors(DT);
            trianglesOfInterest = [Idx1 tNeighbor(Idx1,:)];
            normalsOfInterest = eligibleNormals(trianglesOfInterest,:);
            selectedNormal = mean(normalsOfInterest)/norm(mean(normalsOfInterest));

            intersectionDistance = meshsegtrintersection(point, selectedNormal, 1, P, eligibleT);
            if (any(isnan(intersectionDistance)))
                error('Something went terribly wrong');
            end
            intersectionDistance = intersectionDistance(intersectionDistance~=0);

            
%             Idx2 = rangesearch(eligibleCenters, nF, 0.01);
%             Idx2 = Idx2{1};
%             range = eligibleCenters(Idx2,:);
%             nors = eligibleNormals(Idx2,:);
% 
%             %   Find vectors from the user's point to the range of points on skin
%             A = zeros(size(range,1),3);
% 
%             for i=1:size(range, 1)
%                 A(i,:) = range(i,:) - nF;
%             end
% 
%             %   Find the best vector by comparing to normal skin vectors
%             retV = [0 0 0];
%             angl = 360;
%             for i=1:size(range, 1)
%                 CosTheta = dot(A(i,:), nors(i,:)) ./ norm(A(i,:));
%                 %CosTheta = max(min(dot(range(i, :),nors(i, :))/(norm(range(i, :))*norm(nors(i, :))),1),-1);
%                 ThetaInDegrees = real(acosd(CosTheta));
%                 if ThetaInDegrees < angl
%                     retV = nors(i, :);
%                     angl = ThetaInDegrees;
%                     app.skinPoint = range(i,:);
%                 end
%             end

            app.skinPoint = point + intersectionDistance*selectedNormal;

            %   Get direction vectors
            app.directionNVector = selectedNormal;

            %   Translate the coil
            translations = app.skinPoint + app.directionNVector .* app.RadiusmmSlider.Value * 1e-2;   %mm
            translations = translations .* 1e2;
            app.MatrixField14.Value = translations(1);
            app.MatrixField24.Value = translations(2);
            app.MatrixField34.Value = translations(3);


            %   Code for rotating coil towards the head
            normalVect = app.directionNVector;

            angle = 0;  %Unnecessary to have any change to this
            a = [0,0,1];   % Vector we are starting at
            b = normalVect; % Vector we want
            v = cross(a, b);
            s = norm(cross(a,b)) * sin(angle);
            c = dot(a,b)*cos(angle);
            vCrossMatrix = [0, -v(3), v(2); v(3), 0, -v(1);-v(2), v(1), 0];

            rotationMatrix = eye(3) + vCrossMatrix + vCrossMatrix^2 * 1/(1+c);
            app.MatrixField11.Value = rotationMatrix(1,1);
            app.MatrixField12.Value = rotationMatrix(1,2);
            app.MatrixField13.Value = rotationMatrix(1,3);
            app.MatrixField21.Value = rotationMatrix(2,1);
            app.MatrixField22.Value = rotationMatrix(2,2);
            app.MatrixField23.Value = rotationMatrix(2,3);
            app.MatrixField31.Value = rotationMatrix(3,1);
            app.MatrixField32.Value = rotationMatrix(3,2);
            app.MatrixField33.Value = rotationMatrix(3,3);


            %   Plot the point on the skin and the user-chosen point
            plot3(app.UIAxes, point(1), point(2), point(3), 'r*', 'MarkerSize', 5000);
            plot3(app.UIAxes, app.skinPoint(1), app.skinPoint(2), app.skinPoint(3), 'm*', 'MarkerSize', 5000);

            %   Update display
            updateImage(app);

        end

        % Button pushed function: UpdatePointButton
        function UpdatePointButtonPushed(app, event)
            %   Updates point param to edit fields
            info_struct = getCursorInfo(app.dcm_obj);
            if isfield(info_struct, 'Position')
                point = info_struct.Position .* 1e-2;

                app.PointXValEditField.Value = round(point(1), 3);
                app.PointYValEditField.Value = round(point(2), 3);
                app.PointZValEditField.Value = round(point(3), 3);
            end
        end

        % Value changed function: TransformationMatrixSwitch
        function TransformationMatrixSwitchValueChanged(app, event)
            value = app.TransformationMatrixSwitch.Value;
            switch value
                case 'Off'

                    %   Disables visibility of the Transformation Matrix
                    app.TransformationMatrixPanel.Visible = "off";

                case 'On'

                    %   Enables visibility of the Transformation Matrix
                    app.TransformationMatrixPanel.Visible = "on";
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.TransformationMatrixPanel = uipanel(app.UIFigure);
            app.TransformationMatrixPanel.Title = 'Transformation Matrix';
            app.TransformationMatrixPanel.Visible = 'off';
            app.MatrixField21 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField21.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField31 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField31.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField41 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField41.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField41.Editable = 'off';
            app.MatrixField42 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField42.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField42.Editable = 'off';
            app.MatrixField11 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField11.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField11.Value = 1;
            app.MatrixField13 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField13.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField32 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField32.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField22 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField22.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField22.Value = 1;
            app.MatrixField12 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField12.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField14 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField14.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField23 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField23.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField43 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField43.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField43.Editable = 'off';
            app.MatrixField24 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField24.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField34 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField34.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField33 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField33.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField33.Value = 1;
            app.MatrixField44 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField44.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField44.Editable = 'off';
            app.MatrixField44.Value = 1;
            app.SliderControlsPanel = uipanel(app.UIFigure);
            app.SliderControlsPanel.Title = 'Slider Controls';
            app.XTranslationSliderLabel = uilabel(app.SliderControlsPanel);
            app.XTranslationSliderLabel.Tooltip = {'The x-component of the coil''s translation'};
            app.XTranslationSliderLabel.Text = 'X Translation';
            app.XTranslationSlider = uislider(app.SliderControlsPanel);
            app.XTranslationSlider.Limits = [-20 20];
            app.XTranslationSlider.MajorTicks = [-20 -10 0 10 20];
            app.XTranslationSlider.ValueChangedFcn = createCallbackFcn(app, @XTranslationSliderValueChanged, true);
            app.XTranslationSlider.MinorTicks = [-17.5 -15 -12.5 -7.5 -5 -2.5 2.5 5 7.5 12.5 15 17.5];
            app.XTranslationSlider.Tooltip = {'Applies a translation to the coil along the x-axis'};
            app.YTranslationSliderLabel = uilabel(app.SliderControlsPanel);
            app.YTranslationSliderLabel.Tooltip = {'The y-component of the coil''s translation'};
            app.YTranslationSliderLabel.Text = 'Y Translation';
            app.YTranslationSlider = uislider(app.SliderControlsPanel);
            app.YTranslationSlider.Limits = [-20 20];
            app.YTranslationSlider.MajorTicks = [-20 -10 0 10 20];
            app.YTranslationSlider.ValueChangedFcn = createCallbackFcn(app, @YTranslationSliderValueChanged, true);
            app.YTranslationSlider.MinorTicks = [-17.5 -15 -12.5 -7.5 -5 -2.5 2.5 5 7.5 12.5 15 17.5];
            app.YTranslationSlider.Tooltip = {'Applies a translation to the coil along the y-axis'};
            app.ZTranslationSliderLabel = uilabel(app.SliderControlsPanel);
            app.ZTranslationSliderLabel.Tooltip = {'The z-component of the coil''s translation'};
            app.ZTranslationSliderLabel.Text = 'Z Translation';
            app.ZTranslationSlider = uislider(app.SliderControlsPanel);
            app.ZTranslationSlider.Limits = [-20 20];
            app.ZTranslationSlider.MajorTicks = [-20 -10 0 10 20];
            app.ZTranslationSlider.ValueChangedFcn = createCallbackFcn(app, @ZTranslationSliderValueChanged, true);
            app.ZTranslationSlider.MinorTicks = [-17.5 -15 -12.5 -7.5 -5 -2.5 2.5 5 7.5 12.5 15 17.5];
            app.ZTranslationSlider.Tooltip = {'Applies a translation to the coil along the z-axis'};
            app.XAxisRotationSliderLabel = uilabel(app.SliderControlsPanel);
            app.XAxisRotationSliderLabel.Text = 'X-Axis Rotation';
            app.XAxisRotationSlider = uislider(app.SliderControlsPanel);
            app.XAxisRotationSlider.Limits = [-1 1];
            app.XAxisRotationSlider.MajorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.XAxisRotationSlider.MajorTickLabels = {'-1', '-0.75', '-0.5', '-0.25', '0', '0.25', '0.5', '0.75', '1'};
            app.XAxisRotationSlider.ValueChangedFcn = createCallbackFcn(app, @XAxisRotationSliderValueChanged, true);
            app.XAxisRotationSlider.MinorTicks = [-0.875 -0.625 -0.375 -0.125 0.125 0.375 0.625 0.875];
            app.XAxisRotationSlider.Tooltip = {'Applies a rotation to the coil around the x-axis'};
            app.YAxisRotationSliderLabel = uilabel(app.SliderControlsPanel);
            app.YAxisRotationSliderLabel.Text = 'Y-Axis Rotation';
            app.YAxisRotationSlider = uislider(app.SliderControlsPanel);
            app.YAxisRotationSlider.Limits = [-1 1];
            app.YAxisRotationSlider.MajorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.YAxisRotationSlider.MajorTickLabels = {'-1', '-0.75', '-0.5', '-0.25', '0', '0.25', '0.5', '0.75', '1'};
            app.YAxisRotationSlider.ValueChangedFcn = createCallbackFcn(app, @YAxisRotationSliderValueChanged, true);
            app.YAxisRotationSlider.MinorTicks = [-0.875 -0.625 -0.375 -0.125 0.125 0.375 0.625 0.875];
            app.YAxisRotationSlider.Tooltip = {'Applies a rotation to the coil around the y-axis'};
            app.CoilAxisRotationSliderLabel = uilabel(app.SliderControlsPanel);
            app.CoilAxisRotationSliderLabel.Text = 'Coil-Axis Rotation';
            app.CoilAxisRotationSlider = uislider(app.SliderControlsPanel);
            app.CoilAxisRotationSlider.Limits = [-1 1];
            app.CoilAxisRotationSlider.MajorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.CoilAxisRotationSlider.MajorTickLabels = {'-1', '-0.75', '-0.5', '-0.25', '0', '0.25', '0.5', '0.75', '1'};
            app.CoilAxisRotationSlider.ValueChangedFcn = createCallbackFcn(app, @CoilAxisRotationSliderValueChanged, true);
            app.CoilAxisRotationSlider.MinorTicks = [-0.875 -0.625 -0.375 -0.125 0.125 0.375 0.625 0.875];
            app.CoilAxisRotationSlider.Tooltip = {'Applies a rotation to the coil around the coil''s axis'};
            app.ResetRotationButton = uibutton(app.SliderControlsPanel, 'push');
            app.ResetRotationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetRotationButtonPushed, true);
            app.ResetRotationButton.Tooltip = {'Resets the rotation of the coil'};
            app.ResetRotationButton.Text = 'Reset Rotation';
            app.ResetTranslationButton = uibutton(app.SliderControlsPanel, 'push');
            app.ResetTranslationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTranslationButtonPushed, true);
            app.ResetTranslationButton.Tooltip = {'Resets the translation of the coil'};
            app.ResetTranslationButton.Text = 'Reset Translation';
            app.UpdatePointButton = uibutton(app.SliderControlsPanel, 'push');
            app.UpdatePointButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePointButtonPushed, true);
            app.UpdatePointButton.Visible = 'off';
            app.UpdatePointButton.Tooltip = {'Overwrites the point in the program. Only do this for point on the GM layer'};
            app.UpdatePointButton.Text = 'UpdatePoint';
            app.RadiusmmSliderLabel = uilabel(app.SliderControlsPanel);
            app.RadiusmmSliderLabel.Visible = 'off';
            app.RadiusmmSliderLabel.Text = 'Radius (mm)';
            app.RadiusmmSlider = uislider(app.SliderControlsPanel);
            app.RadiusmmSlider.Limits = [0 20];
            app.RadiusmmSlider.MajorTicks = [0 5 10 15 20];
            app.RadiusmmSlider.MinorTicks = [1 2 3 4 6 7 8 9 11 12 13 14 16 17 18 19];
            app.RadiusmmSlider.Visible = 'off';
            app.RadiusmmSlider.Tooltip = {'The distance from the skin for the coil'};
            app.UpdateDisplayButton = uibutton(app.SliderControlsPanel, 'push');
            app.UpdateDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDisplayButtonPushed, true);
            app.UpdateDisplayButton.Visible = 'off';
            app.UpdateDisplayButton.Tooltip = {'Updates the display to reflect the point and the radius values'};
            app.UpdateDisplayButton.Text = 'Update Display';
            app.PointXValEditField = uieditfield(app.SliderControlsPanel, 'numeric');
            app.PointXValEditField.Visible = 'off';
            app.PointXValEditField.Tooltip = {'The targeted point''s x-coordinate'};
            app.PointYValEditField = uieditfield(app.SliderControlsPanel, 'numeric');
            app.PointYValEditField.Visible = 'off';
            app.PointYValEditField.Tooltip = {'The targeted point''s y-coordinate'};
            app.PointZValEditField = uieditfield(app.SliderControlsPanel, 'numeric');
            app.PointZValEditField.Visible = 'off';
            app.PointZValEditField.Tooltip = {'The targeted point''s z-coordinate'};
            app.ChosenPointcmLabel = uilabel(app.SliderControlsPanel);
            app.ChosenPointcmLabel.Visible = 'off';
            app.ChosenPointcmLabel.Text = 'Chosen Point (cm)';
            app.Panel = uipanel(app.UIFigure);
            app.AxisLengthSliderLabel = uilabel(app.Panel);
            app.AxisLengthSliderLabel.Text = 'Axis Length';
            app.AxisLengthSlider = uislider(app.Panel);
            app.AxisLengthSlider.Limits = [1 40];
            app.AxisLengthSlider.MajorTicks = [1 10 20 30 40];
            app.AxisLengthSlider.ValueChangedFcn = createCallbackFcn(app, @AxisLengthSliderValueChanged, true);
            app.AxisLengthSlider.MinorTicks = [5 15 25 35];
            app.AxisLengthSlider.Tooltip = {'Change the axis size'};
            app.AxisLengthSlider.Value = 20;
            app.ContributorsAltonMilesGrahamOrnsteinKseniiaRomanovaPanel = uipanel(app.UIFigure);
            app.ContributorsAltonMilesGrahamOrnsteinKseniiaRomanovaPanel.Title = 'Contributors: Alton Miles, Graham Ornstein, Kseniia Romanova';
            app.Versionv012alphaLabel = uilabel(app.ContributorsAltonMilesGrahamOrnsteinKseniiaRomanovaPanel);
            app.Versionv012alphaLabel.Text = 'Version: v0.1.2-alpha';
            app.HeadCompartmentsButtonGroup = uibuttongroup(app.UIFigure);
            app.HeadCompartmentsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HeadCompartmentsButtonGroupSelectionChanged, true);
            app.HeadCompartmentsButtonGroup.Tooltip = {'Swap between compartments on the head'};
            app.HeadCompartmentsButtonGroup.Title = 'Head Compartments';
            app.HeadCompartmentsButtonGroup.ButtonDownFcn = createCallbackFcn(app, @HeadCompartmentsButtonGroupButtonDown, true);
            app.SkinButton_2 = uitogglebutton(app.HeadCompartmentsButtonGroup);
            app.SkinButton_2.Text = 'Skin';
            app.SkinButton_2.Value = true;
            app.BoneButton = uitogglebutton(app.HeadCompartmentsButtonGroup);
            app.BoneButton.Text = 'Bone';
            app.CSFButton = uitogglebutton(app.HeadCompartmentsButtonGroup);
            app.CSFButton.Text = 'CSF';
            app.GMButton = uitogglebutton(app.HeadCompartmentsButtonGroup);
            app.GMButton.Text = 'GM';
            app.WMButton = uitogglebutton(app.HeadCompartmentsButtonGroup);
            app.WMButton.Text = 'WM';
            app.VentriclesButton = uitogglebutton(app.HeadCompartmentsButtonGroup);
            app.VentriclesButton.Text = 'Ventricles';
            app.EyesButton = uitogglebutton(app.HeadCompartmentsButtonGroup);
            app.EyesButton.Text = 'Eyes';
            app.VisualControlsPanel = uipanel(app.UIFigure);
            app.VisualControlsPanel.Title = 'Visual Controls';
            app.VectortocoilSwitchLabel = uilabel(app.VisualControlsPanel);
            app.VectortocoilSwitchLabel.Text = 'Vector to coil';
            app.VectortocoilSwitch = uiswitch(app.VisualControlsPanel, 'slider');
            app.VectortocoilSwitch.ValueChangedFcn = createCallbackFcn(app, @VectortocoilSwitchValueChanged, true);
            app.VectortocoilSwitch.Tooltip = {'Toggle a vector from the brain to the coil'};
            app.VectorfromcoilSwitchLabel = uilabel(app.VisualControlsPanel);
            app.VectorfromcoilSwitchLabel.Text = 'Vector from coil';
            app.VectorfromcoilSwitch = uiswitch(app.VisualControlsPanel, 'slider');
            app.VectorfromcoilSwitch.ValueChangedFcn = createCallbackFcn(app, @VectortocoilSwitchValueChanged, true);
            app.VectorfromcoilSwitch.Tooltip = {'Toggle a vector showing the coil''s direction'};
            app.PresetsTestingOnlyButtonGroup = uibuttongroup(app.UIFigure);
            app.PresetsTestingOnlyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PresetButton, true);
            app.PresetsTestingOnlyButtonGroup.Title = 'Presets [Testing Only]';
            app.ResetButton = uitogglebutton(app.PresetsTestingOnlyButtonGroup);
            app.ResetButton.Text = 'Reset';
            app.ResetButton.Value = true;
            app.Preset2Button = uitogglebutton(app.PresetsTestingOnlyButtonGroup);
            app.Preset2Button.Text = 'Preset 2';
            app.Preset3Button = uitogglebutton(app.PresetsTestingOnlyButtonGroup);
            app.Preset3Button.Text = 'Preset 3';
            app.TranslationTypeSwitchLabel = uilabel(app.UIFigure);
            app.TranslationTypeSwitchLabel.Text = 'Translation Type Switch';
            app.TranslationTypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.TranslationTypeSwitch.Items = {'Manual', 'Automatic'};
            app.TranslationTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @TranslationTypeSwitchValueChanged, true);
            app.TranslationTypeSwitch.Tooltip = {'Swaps between translation and point controls for the coil'};
            app.TranslationTypeSwitch.Value = 'Manual';
            app.TransformationMatrixSwitchLabel = uilabel(app.UIFigure);
            app.TransformationMatrixSwitchLabel.Text = 'Transformation Matrix Switch';
            app.TransformationMatrixSwitch = uiswitch(app.UIFigure, 'slider');
            app.TransformationMatrixSwitch.ValueChangedFcn = createCallbackFcn(app, @TransformationMatrixSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end